classdef visualisation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        NoLoadLamp            matlab.ui.control.Lamp
        NoLoadLampLabel       matlab.ui.control.Label
        StartButton           matlab.ui.control.Button
        DurationsSlider       matlab.ui.control.Slider
        DurationsSliderLabel  matlab.ui.control.Label
        CDropDown             matlab.ui.control.DropDown
        CDropDownLabel        matlab.ui.control.Label
        RsDropDown            matlab.ui.control.DropDown
        RsDropDownLabel       matlab.ui.control.Label
        SwitchBOnButton       matlab.ui.control.StateButton
        SwitchAOnButton       matlab.ui.control.StateButton
        CurrentPlot           matlab.ui.control.UIAxes
        PowerPlot             matlab.ui.control.UIAxes
        VoltagePlot           matlab.ui.control.UIAxes
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        Duration double {mustBeReal(Duration), mustBePositive(Duration), mustBeNonmissing(Duration), mustBeFinite(Duration)} = 1; % Duration of the simulation
    end
    
    properties (Access = public)
        C {mustBePositive(C), mustBeNonmissing(C)} = 2200e-4;
        Mode CircuitMode
        Rs {mustBeReal(Rs), mustBePositive(Rs), mustBeNonmissing(Rs), mustBeInteger(Rs), mustBeFinite(Rs)} = 300;
        Running
        SwitchAOn
        SwitchBOn
    end
    
    
    methods (Access = private)
        
        function stop(comp)
            if ~comp.Running
                return
            end

            comp.NoLoadLamp.Color = [0.502 0.502 0.502];
            comp.Running = false;
            comp.StartButton.Text = 'Start';
        end

        function start(comp)
            if comp.Running
                return
            end

            comp.NoLoadLamp.Color = [0 1 0];
            comp.Running = true;
            comp.StartButton.Text = 'Stop';

            h = 2e-5;
            ts = 0;
            te = comp.Duration;
            t = ts:h:te;
            v = @(t) 230 * sqrt(2) * sin(100*pi*t);  
            ic = [0,0,0];

            e2Line = animatedline(comp.VoltagePlot,'Color','b');
            e3Line = animatedline(comp.VoltagePlot,'Color','r');
            iZline = animatedline(comp.CurrentPlot,'Color','m');

            grid(comp.VoltagePlot, 'on');
            legend(comp.VoltagePlot, 'e2', 'e3')

            grid(comp.CurrentPlot, 'on');
            legend(comp.CurrentPlot, 'iZ');

            for k=1:length(t)-1
                if ~comp.Running; end
                tn = t(k);
                tn_1 = tn + h;
                tn_2 = tn + h/2;
                Vin = [v(tn), v(tn_2), v(tn_1)];
                g = powerSupply(comp.Mode,Vin,h,ic);
                ic(1) = g.e2;
                ic(2) = g.e3;
                ic(3) = g.iL;
                addpoints(e2Line,tn,g.e2)
                addpoints(e3Line,tn,g.e3)
                addpoints(iZline,tn,g.Iz);
                drawnow limitrate
            end
            stop(comp)


%             t = 0:0.1:comp.Duration;
%             y = sin(t);
%             clf(comp.VoltagePlot,'reset')
%             h = plot(comp.VoltagePlot,nan,nan); 
%             hold(comp.VoltagePlot, 'on')
%             grid(comp.VoltagePlot, 'on')
%             xlim(comp.VoltagePlot, [0 comp.Duration])
%             ylim(comp.VoltagePlot, [-1.5 1.5])
%             for k = 1:numel(t)
%                 if ~comp.Running; break; end
%                 set(h,'XData',t(1:k), 'YData', y(1:k))   
%                 drawnow(); pause(0.01115)
%             end 
        end

        function toggle(comp)
            if comp.Running
                stop(comp)
            else
                start(comp)
            end
        end
        
        function restart(comp)
            stop(comp);
            start(comp);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Running = false;
            comp.SwitchAOn = false;
            comp.SwitchBOn = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(comp, event)
            toggle(comp)
        end

        % Value changed function: RsDropDown
        function RsDropDownValueChanged(comp, event)
            comp.Rs = comp.RsDropDown.Value;
            restart(comp);
        end

        % Value changed function: CDropDown
        function CDropDownValueChanged(comp, event)
            comp.C = comp.CDropDown.Value;
            restart(comp);
        end

        % Value changed function: SwitchAOnButton
        function SwitchAOnButtonValueChanged(comp, event)
            comp.SwitchAOn = comp.SwitchAOnButton.Value;
            if comp.SwitchAOn
                comp.SwitchAOnButton.Text = 'Switch A: Off';
            else
                comp.SwitchAOnButton.Text = 'Switch A: On';
            end
            restart(comp);
        end

        % Value changed function: SwitchBOnButton
        function SwitchBOnButtonValueChanged(comp, event)
            comp.SwitchBOn = comp.SwitchBOnButton.Value;
            if comp.SwitchBOn
                comp.SwitchBOnButton.Text = 'Switch B: Off';
            else
                comp.SwitchBOnButton.Text = 'Switch B: On';
            end
            restart(comp);
        end

        % Value changed function: DurationsSlider
        function DurationsSliderValueChanged(comp, event)
            comp.Duration = comp.DurationsSlider.Value;
            restart(comp)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            if comp.SwitchAOn && comp.SwitchBOn
                comp.Mode = CircuitMode.FullLoad;
            elseif comp.SwitchAOn && ~comp.SwitchBOn
                comp.Mode = CircuitMode.ResistiveLoad;
            elseif comp.SwitchBOn && ~comp.SwitchAOn
                comp.Mode = CircuitMode.InductiveLoad;
            else
                comp.Mode = CircuitMode.NoLoad;
            end
            comp.NoLoadLampLabel.Text = char(comp.Mode);
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1167 803];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.VoltagePlot = uiaxes(comp);
            title(comp.VoltagePlot, 'Output Voltages')
            xlabel(comp.VoltagePlot, 'Time (s)')
            ylabel(comp.VoltagePlot, 'Voltage (V)')
            zlabel(comp.VoltagePlot, 'Z')
            comp.PowerPlot = uiaxes(comp);
            title(comp.PowerPlot, 'Power Dissipation')
            xlabel(comp.PowerPlot, 'X')
            ylabel(comp.PowerPlot, 'Y')
            zlabel(comp.PowerPlot, 'Z')
            comp.CurrentPlot = uiaxes(comp);
            title(comp.CurrentPlot, 'Output Currents')
            xlabel(comp.CurrentPlot, 'Time (s)')
            ylabel(comp.CurrentPlot, 'Current (A)')
            zlabel(comp.CurrentPlot, 'Z')
            comp.SwitchAOnButton = uibutton(comp, 'state');
            comp.SwitchAOnButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SwitchAOnButtonValueChanged, true);
            comp.SwitchAOnButton.Text = 'Switch A: On';
            comp.SwitchBOnButton = uibutton(comp, 'state');
            comp.SwitchBOnButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SwitchBOnButtonValueChanged, true);
            comp.SwitchBOnButton.Text = 'Switch B: On';
            comp.RsDropDownLabel = uilabel(comp);
            comp.RsDropDownLabel.Text = 'Rs';
            comp.RsDropDown = uidropdown(comp);
            comp.RsDropDown.Items = {'300 Ohms', '270 Ohms', '330 Ohms'};
            comp.RsDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RsDropDownValueChanged, true);
            comp.RsDropDown.Value = '330 Ohms';
            comp.CDropDownLabel = uilabel(comp);
            comp.CDropDownLabel.Text = 'C';
            comp.CDropDown = uidropdown(comp);
            comp.CDropDown.Items = {'2.2mF', '2.42mF', '1.98mF'};
            comp.CDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CDropDownValueChanged, true);
            comp.CDropDown.Value = '2.2mF';
            comp.DurationsSliderLabel = uilabel(comp);
            comp.DurationsSliderLabel.Text = 'Duration (s)';
            comp.DurationsSlider = uislider(comp);
            comp.DurationsSlider.Limits = [0.05 3];
            comp.DurationsSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DurationsSliderValueChanged, true);
            comp.DurationsSlider.Value = 1;
            comp.StartButton = uibutton(comp, 'push');
            comp.StartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartButtonPushed, true);
            comp.StartButton.Text = 'Start';
            comp.NoLoadLampLabel = uilabel(comp);
            comp.NoLoadLampLabel.Text = 'No Load';
            comp.NoLoadLamp = uilamp(comp);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end